classdef lab1_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                     matlab.ui.Figure
        uipanel1                    matlab.ui.container.Panel
        text1                       matlab.ui.control.Label
        text2                       matlab.ui.control.Label
        edit1                       matlab.ui.control.EditField
        edit2                       matlab.ui.control.EditField
        pushbutton1                 matlab.ui.control.Button
        text3                       matlab.ui.control.Label
        edit3                       matlab.ui.control.EditField
        ResultsPanel                matlab.ui.container.Panel
        rt9utintermsofVoltsLabel    matlab.ui.control.Label
        YourDCmotorisdesignedLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Lab1_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to prelab2 (see VARARGIN)
%             
%             % Choose default command line output for prelab2
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);

            app.UIAxes.HandleVisibility = 'off';
            app.ResultsPanel.HandleVisibility = 'off';
        end

        % Button pushed function: pushbutton1
        function pushbutton1ButtonPushed(app, event)
            clock_c = (15+mod(sum(double(app.edit1.Value)),15))*0.01;
            assignin('base', 'clock_c', clock_c);
            temp_id = (app.edit2.Value);
            id = zeros(1,length(temp_id));
            for m=1:length(temp_id)
                id(m) = str2double(temp_id(m));
            end
            sec = str2double(app.edit3.Value);
            a1 = max(8,sum(id(1:3)))+6.5-sec*randi(100)/100;
            a2 = 1/(1000+polyval(id(5:8),10));
            a3 = 1/(max(6,sum(id)/3));
            DCmotor.n = a1; DCmotor.d = [a2 a3 1];
            assignin('base', 'DCmotor', DCmotor);
            model = 'lab1_DCmotor';
            load_system(model);
            simOut=sim(model,'StartTime','0','StopTime','10','FixedStep','0.01');
            plot(app.UIAxes,simOut.simout.time,simOut.simout.data);
            app.UIAxes.Visible = 'on';
            app.ResultsPanel.Visible = 'on';
            DCmotor.out=simOut.simout;
            assignin('base', 'DCmotor', DCmotor);
            save('DCmotor.mat',"DCmotor");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Lab-1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Info';
            app.text1 = uilabel(app.uipanel1);
            app.text1.Text = 'Name:';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Text = 'Student ID:';
            app.edit1 = uieditfield(app.uipanel1, 'text');
            app.edit1.Value = 'Mustafa Oğuz Yeğin';
            app.edit2 = uieditfield(app.uipanel1, 'text');
            app.edit2.Value = '21001843';
            app.pushbutton1 = uibutton(app.uipanel1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1ButtonPushed, true);
            app.pushbutton1.Text = 'Run My Motor';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Text = 'Section:';
            app.edit3 = uieditfield(app.uipanel1, 'text');
            app.edit3.Value = '1';
            app.ResultsPanel = uipanel(app.figure1);
            app.ResultsPanel.Title = 'Results';
            app.ResultsPanel.Visible = 'off';
            app.rt9utintermsofVoltsLabel = uilabel(app.ResultsPanel);
            app.rt9utintermsofVoltsLabel.Text = 'r(t) = 9u(t)    in terms of Volts';
            app.YourDCmotorisdesignedLabel = uilabel(app.ResultsPanel);
            app.YourDCmotorisdesignedLabel.Text = {'            Your DC motor is designed.'; ''; '     Now, you can use the Simulink files.'};
            app.UIAxes = uiaxes(app.figure1);
            title(app.UIAxes, 'Output of Your Model vs DC Motor')
            xlabel(app.UIAxes, 'Time (sec)')
            ylabel(app.UIAxes, 'Ouput (rpm)')
            app.UIAxes.Visible = 'off';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab1_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Lab1_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end