classdef lab3_DCmotor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                     matlab.ui.Figure
        uipanel1                    matlab.ui.container.Panel
        text2                       matlab.ui.control.Label
        text3                       matlab.ui.control.Label
        edit1                       matlab.ui.control.EditField
        edit2                       matlab.ui.control.EditField
        text4                       matlab.ui.control.Label
        edit3                       matlab.ui.control.EditField
        edit4                       matlab.ui.control.EditField
        text5                       matlab.ui.control.Label
        pushbutton1                 matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        ResultsPanel                matlab.ui.container.Panel
        rt9utintermsofVoltsLabel    matlab.ui.control.Label
        YourDCmotorisdesignedLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function prelab2_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to prelab2 (see VARARGIN)
%             
%             % Choose default command line output for prelab2
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);

            app.UIAxes.HandleVisibility = 'off';
            app.ResultsPanel.HandleVisibility = 'off';
        end

        % Button pushed function: pushbutton1
        function pushbutton1ButtonPushed(app, event)
            clock_c = (25+mod(sum(double(app.edit1.Value)),25))*0.01;
            assignin('base', 'clock_c', clock_c);
            id = app.edit2.Value;
            k = str2double(app.edit3.Value)*0.98;
            assignin('base', 'k', k);
            tau = (str2double(id(3))+10)/500+str2double(app.edit4.Value)*0.9;
            assignin('base', 'tau', tau);
            my_TF = tf(9*str2double(app.edit3.Value),[str2double(app.edit4.Value) 1]);
            t = 1e-2:1e-2:10;
            stepResp = step(my_TF,t);
            z = (str2double(id(1:2)) + str2double(id(5)))*2;
            assignin('base', 'z', z);
            p = mod(str2double(id(6:8)),100)+100;
            assignin('base', 'p', p);
            model = 'prelab2_DCmotor';
            load_system(model);
            simOut=sim(model,'StartTime','0','StopTime','10','FixedStep','0.01');
            plot(app.UIAxes,simOut.simout.time,simOut.simout.data); 
            hold(app.UIAxes,'on');
            plot(app.UIAxes,t,stepResp); 
            app.UIAxes.Visible = 'on';
            app.ResultsPanel.Visible = 'on';
            DCmotor.k = k; DCmotor.tau = tau;
            DCmotor.z = z; DCmotor.p = p;
            DCmotor.clock_c = clock_c;
            assignin('base', 'DCmotor', DCmotor);
            save('DCmotor.mat',"DCmotor");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'prelab2';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Info';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Text = 'Name:';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Text = 'Student ID:';
            app.edit1 = uieditfield(app.uipanel1, 'text');
            app.edit1.Value = 'Mustafa Oğuz Yeğin';
            app.edit2 = uieditfield(app.uipanel1, 'text');
            app.edit2.Value = '21001843';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Text = 'G(s) = ---------------';
            app.edit3 = uieditfield(app.uipanel1, 'text');
            app.edit3.Value = 'K';
            app.edit4 = uieditfield(app.uipanel1, 'text');
            app.edit4.Value = 'Tau';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Text = 's + 1';
            app.pushbutton1 = uibutton(app.uipanel1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1ButtonPushed, true);
            app.pushbutton1.Text = 'Build My Motor';
            app.UIAxes = uiaxes(app.figure1);
            title(app.UIAxes, 'Output of Your Model vs DC Motor')
            xlabel(app.UIAxes, 'Time (sec)')
            ylabel(app.UIAxes, 'Ouput (rpm)')
            app.UIAxes.Visible = 'off';
            app.ResultsPanel = uipanel(app.figure1);
            app.ResultsPanel.Title = 'Results';
            app.ResultsPanel.Visible = 'off';
            app.rt9utintermsofVoltsLabel = uilabel(app.ResultsPanel);
            app.rt9utintermsofVoltsLabel.Text = 'r(t) = 9u(t)    in terms of Volts';
            app.YourDCmotorisdesignedLabel = uilabel(app.ResultsPanel);
            app.YourDCmotorisdesignedLabel.Text = {'            Your DC motor is designed.'; ''; '     Now, you can use the Simulink files.'};
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab3_DCmotor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @prelab2_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end